classdef USV_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        USV_App_Figure                  matlab.ui.Figure
        Tabs                            matlab.ui.container.TabGroup
        Tab_Files                       matlab.ui.container.Tab
        Grid_Files                      matlab.ui.container.GridLayout
        Button_SelectReferenceLabelFile  matlab.ui.control.Button
        Field_SelectReferenceLabelFile  matlab.ui.control.EditField
        Button_SelectUSVFile            matlab.ui.control.Button
        Field_SelectUSVFile             matlab.ui.control.EditField
        Button_SelectLabelFile          matlab.ui.control.Button
        Field_SelectLabelFile           matlab.ui.control.EditField
        Button_LoadFiles                matlab.ui.control.Button
        Tab_Visualisation               matlab.ui.container.Tab
        Grid_Visualisation              matlab.ui.container.GridLayout
        Button_Play                     matlab.ui.control.Button
        Button_Plot                     matlab.ui.control.Button
        Button_SegmentMinus             matlab.ui.control.Button
        Button_SegmentPlus              matlab.ui.control.Button
        Field_SegmentStart              matlab.ui.control.NumericEditField
        Field_SegmentLength             matlab.ui.control.NumericEditField
        SegmentLengthsEditFieldLabel    matlab.ui.control.Label
        SegmentStartsEditFieldLabel     matlab.ui.control.Label
        UIAxes_USV                      matlab.ui.control.UIAxes
        UIAxes_Spectrogram              matlab.ui.control.UIAxes
        Tab_Statistics                  matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Field_F1Score_Segment           matlab.ui.control.NumericEditField
        Field_Recall_Segment            matlab.ui.control.NumericEditField
        Field_Precision_Segment         matlab.ui.control.NumericEditField
        Field_FalseNegatives_Segment    matlab.ui.control.NumericEditField
        Field_FalsePositives_Segment    matlab.ui.control.NumericEditField
        Field_TruePositives_Segment     matlab.ui.control.NumericEditField
        Field_ReferenceLabels_Segment   matlab.ui.control.NumericEditField
        Field_DetectedLabels_Segment    matlab.ui.control.NumericEditField
        SegmentLabel                    matlab.ui.control.Label
        AllLabel                        matlab.ui.control.Label
        DetectionmetricsLabel           matlab.ui.control.Label
        Field_DetectedLabels            matlab.ui.control.NumericEditField
        Field_ReferenceLabels           matlab.ui.control.NumericEditField
        Field_TruePositives             matlab.ui.control.NumericEditField
        Field_FalsePositives            matlab.ui.control.NumericEditField
        Field_FalseNegatives            matlab.ui.control.NumericEditField
        Field_Precision                 matlab.ui.control.NumericEditField
        Field_Recall                    matlab.ui.control.NumericEditField
        Field_F1Score                   matlab.ui.control.NumericEditField
        DetectedLabelsEditFieldLabel_8  matlab.ui.control.Label
        DetectedLabelsEditFieldLabel_7  matlab.ui.control.Label
        DetectedLabelsEditFieldLabel_6  matlab.ui.control.Label
        DetectedLabelsEditFieldLabel_5  matlab.ui.control.Label
        DetectedLabelsEditFieldLabel_4  matlab.ui.control.Label
        DetectedLabelsEditFieldLabel_3  matlab.ui.control.Label
        DetectedLabelsEditFieldLabel_2  matlab.ui.control.Label
        DetectedLabelsEditFieldLabel    matlab.ui.control.Label
        AcceptRejectTab                 matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        LabelTypeEditField              matlab.ui.control.EditField
        LabelTypeEditFieldLabel         matlab.ui.control.Label
        Button_USVAR_Prev               matlab.ui.control.Button
        Button_USVAR_Next               matlab.ui.control.Button
        Field_USV_LabelCount_AR         matlab.ui.control.NumericEditField
        LabelEditFieldLabel_2           matlab.ui.control.Label
        Field_USV_Label_AR              matlab.ui.control.NumericEditField
        LabelEditFieldLabel             matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        LoadDetectedUSVButton           matlab.ui.control.Button
        RejectButton                    matlab.ui.control.Button
        AcceptButton                    matlab.ui.control.Button
        UIAxes_Spectrogram_AR           matlab.ui.control.UIAxes
        DetectionTab                    matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ExportButton_2                  matlab.ui.control.Button
        RunDetectionButton              matlab.ui.control.Button
        DetectorDropDown                matlab.ui.control.DropDown
        DetectorDropDownLabel           matlab.ui.control.Label
        Tab_Settings                    matlab.ui.container.Tab
        BreakpointButton                matlab.ui.control.Button
        Subtabs_Settings                matlab.ui.container.TabGroup
        Subtab_Visualisation            matlab.ui.container.Tab
        Grid_VisualisationSettings      matlab.ui.container.GridLayout
        CheckBox_ShowReferenceLabels    matlab.ui.control.CheckBox
        CheckBox_ShowLabels             matlab.ui.control.CheckBox
        ColormapDropDownLabel           matlab.ui.control.Label
        WindowSizeLabel_3               matlab.ui.control.Label
        Label_SpectrogramSettings       matlab.ui.control.Label
        Field_Spectrogram_WindowSize    matlab.ui.control.NumericEditField
        Field_Spectrogram_WindowOverlap  matlab.ui.control.NumericEditField
        Field_Spectrogram_FrequencyMin  matlab.ui.control.NumericEditField
        Field_Spectrogram_FrequencyMax  matlab.ui.control.NumericEditField
        WindowSizeLabel_2               matlab.ui.control.Label
        WindowOverlapLabel              matlab.ui.control.Label
        Field_Spectrogram_Colormap      matlab.ui.control.DropDown
        WindowSizeLabel                 matlab.ui.control.Label
        SonificationTab                 matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        DetectionTab_2                  matlab.ui.container.Tab
        Tab_Info                        matlab.ui.container.Tab
        Grid_Info                       matlab.ui.container.GridLayout
        Label_AppInfo                   matlab.ui.control.Label
    end


    properties (Access = private)

        USV_Signal
        USV_Labels
        USV_Labels_AR
        USV_ReferenceLabels


        fs

        SegmentStartIndex
        SegmentStopIndex

        USV_Signal_Segment

        USV_Labels_Segment
        USV_ReferenceLabels_Segment

    end

    methods (Access = public)

        function ST = calculateST(spectralCutFreqLow, spectralCutFreqHigh, audibleFreqLow, audibleFreqHigh)
            % Calculate the pivot frequencies
            f_pivot = (spectralCutFreqLow + spectralCutFreqHigh) / 2; % Pivot of original range
            f_target = (audibleFreqLow + audibleFreqHigh) / 2; % Pivot of target range

            % Calculate semitone shift
            ST = 12 * log2(f_target / f_pivot);
        end

        function updateMetrics(app)
    % Calculate overall detection metrics
    metricsAll = calculateMetrics(app.USV_ReferenceLabels, app.USV_Labels);
    
    % Update UI fields for overall metrics
    app.Field_DetectedLabels.Value    = metricsAll.NumDetectedLabels;
    app.Field_ReferenceLabels.Value   = metricsAll.NumReferenceLabels;
    app.Field_TruePositives.Value     = metricsAll.TruePositives;
    app.Field_FalsePositives.Value    = metricsAll.FalsePositives;
    app.Field_FalseNegatives.Value    = metricsAll.FalseNegatives;
    app.Field_Precision.Value         = metricsAll.Precision * 100;  % Percentage
    app.Field_Recall.Value            = metricsAll.Recall * 100;     % Percentage
    app.Field_F1Score.Value           = metricsAll.F1Score;
    
    % Optionally, if you also want to update the segment metrics,
    % first determine the segment boundaries (in seconds)
    segStart = app.Field_SegmentStart.Value;
    segEnd   = segStart + app.Field_SegmentLength.Value;
    
    % Subset labels that are within the segment (this assumes that
    % your labels structure contains numeric StartTime and EndTime fields)
    segLabels = app.USV_Labels( [app.USV_Labels.StartTime] >= segStart & [app.USV_Labels.EndTime] <= segEnd );
    segRefLabels = app.USV_ReferenceLabels( [app.USV_ReferenceLabels.StartTime] >= segStart & [app.USV_ReferenceLabels.EndTime] <= segEnd );
    
    % Calculate metrics for the segment
    metricsSegment = calculateMetrics(segRefLabels, segLabels);

    % Update UI fields for segment metrics
    app.Field_DetectedLabels_Segment.Value    = metricsSegment.NumDetectedLabels;
    app.Field_ReferenceLabels_Segment.Value   = metricsSegment.NumReferenceLabels;
    app.Field_TruePositives_Segment.Value     = metricsSegment.TruePositives;
    app.Field_FalsePositives_Segment.Value    = metricsSegment.FalsePositives;
    app.Field_FalseNegatives_Segment.Value    = metricsSegment.FalseNegatives;
    app.Field_Precision_Segment.Value         = metricsSegment.Precision * 100;
    app.Field_Recall_Segment.Value            = metricsSegment.Recall * 100;
    app.Field_F1Score_Segment.Value           = metricsSegment.F1Score;
end

        
        function PSD(app)
               
                d = uiprogressdlg(app.USV_App_Figure,'Title','Please Wait','Indeterminate','on');
                d.Message = "Running PSD Detection Algorithm";
% ==================== PARAMETERS ====================
fcutMin = 40000;        % Minimum frequency cutoff (Hz)
fcutMax = 120000;       % Maximum frequency cutoff (Hz)
noiseScaling = 1.2;     % Scaling factor to set threshold above noise
mergeGap = 0.05;       % Merge gap in seconds (50 ms)
segmentLength = 4096;   % Segment length for Welch PSD
overlapFactor = 0.5;    % Overlap factor for Welch PSD
fs = 250000;            % Sampling frequency (Hz)
ROIstart = 0;           % Start time of Region of Interest (ROI) in seconds
ROIlength = -1;         % Length of ROI in seconds
% 
% % File Paths
% path = "/Users/gazda/Documents/CTU/Masters/Masters thesis/";
% datapath = fullfile(path, "data");
audioFile = "detection";
% audioPath = fullfile(datapath, "data_gauk_nudz", audioFile + ".wav");

audioPath = app.Field_SelectUSVFile.Value;


diary_name = strcat(audioFile,datestr(now), '_diary.txt');
diary_folder = pwd; 
diary(diary_name);
disp(datestr(now));
fprintf(audioPath);

% ==================== LOAD AUDIO ====================
[x, fs] = audioread(audioPath);
t = (0:length(x)-1) / fs; % Time vector
x = x - mean(x); % Remove DC offset
x = x / max(abs(x)); % Normalize audio

% Extract ROI
xROI = x;
tROI = t;

d.Message = "Calculating PSD";
% ==================== PSD ESTIMATION ====================
% Spectrogram parameters
nfft = segmentLength;            % FFT length
window = hamming(segmentLength); % Window function

% Compute spectrogram
[S, F, T] = spectrogram(xROI, window, round(segmentLength * overlapFactor), nfft, fs);

% Frequency range mask
freqMask = (F >= fcutMin) & (F <= fcutMax);

% Calculate power envelope by summing power in the frequency range of interest
powerEnvelope = sum(abs(S(freqMask, :)).^2, 1);

% Normalize power envelope
powerEnvelope = powerEnvelope / max(powerEnvelope);

% ==================== NOISE TRACKING ====================
% Estimate the noise floor using a moving mean filter
noiseFloor = movmean(powerEnvelope, round(length(powerEnvelope) * 0.1)); % Estimate local noise floor
dynamicThreshold = noiseFloor * noiseScaling; % Set threshold above noise floor

% ==================== DETECTION ====================
binaryDetections = powerEnvelope > dynamicThreshold; % Apply dynamic threshold

% Group sequential detections into single events
groupedDetections = diff([0, binaryDetections, 0]); % Add buffer at start and end
eventStarts = find(groupedDetections == 1); % Indices where detections start
eventEnds = find(groupedDetections == -1) - 1; % Indices where detections end

% Calculate event times
eventTimesStart = T(eventStarts) + ROIstart; % Start times of events
eventTimesEnd = T(eventEnds) + ROIstart;     % End times of events

% ==================== MERGE CLOSE EVENTS ====================
mergedEventStarts = [];
mergedEventEnds = [];

i = 1;
while i <= length(eventTimesStart)
    currentStart = eventTimesStart(i);
    currentEnd = eventTimesEnd(i);
    while i < length(eventTimesStart) && eventTimesStart(i + 1) - currentEnd <= mergeGap
        % Merge with the next event
        i = i + 1;
        currentEnd = eventTimesEnd(i);
    end
    mergedEventStarts = [mergedEventStarts; currentStart];
    mergedEventEnds = [mergedEventEnds; currentEnd];
    i = i + 1;
end

% ==================== OUTPUT ====================
fprintf("Detection complete. Total events detected: %d\n Exported to %s\n", length(mergedEventStarts),pwd);
for i = 1:length(mergedEventStarts)
    fprintf("Event %d: Start Time = %.3f s, End Time = %.3f s\n", i, mergedEventStarts(i), mergedEventEnds(i));
end


% ==================== EXPORTING DETECTIONS ====================
% Prepare output file for events
outputLabelFile = strcat(audioFile,datestr(now, 'yyyy-mm-dd_HH-MM-SS'), "_labels.txt");
fileID = fopen(outputLabelFile, 'w');
%fprintf(fileID, "Start Time (s)\tEnd Time (s)\n"); % Correctly terminated string
for i = 1:length(mergedEventStarts)
    fprintf(fileID, '%.6f\t%.6f\tNone\n', mergedEventStarts(i), mergedEventEnds(i));
    fprintf(fileID, '\\\t0.000000\t0.000000\n');
end
fclose(fileID);


uialert(app.USV_App_Figure, ...
    sprintf("Detection complete.\nTotal events detected: %d\nExported to %s", ...
    length(mergedEventStarts), pwd), ...
    "Detection Complete", "Icon", "success");


        end
    end

    methods (Access = private)

      
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_SelectUSVFile
        function Button_SelectUSVFilePushed(app, event)

            [USV_Signal_filename, USV_Signal_location] = uigetfile('*.wav');
            app.Field_SelectUSVFile.Value = [USV_Signal_location,USV_Signal_filename];

        end

        % Button pushed function: Button_LoadFiles
        function Button_LoadFilesPushed(app, event)

            d = uiprogressdlg(app.USV_App_Figure,'Title','Please Wait','Message','Loading Files','Indeterminate','on');
            
            [app.USV_Signal, app.fs] = audioread(app.Field_SelectUSVFile.Value);
            app.USV_Labels = importLabels(app.Field_SelectLabelFile.Value,app.fs);
            app.USV_Labels_AR = app.USV_Labels;

            app.Field_USV_LabelCount_AR.Value = size(app.USV_Labels_AR,2);
            app.USV_ReferenceLabels = importLabels(app.Field_SelectReferenceLabelFile.Value,app.fs);


            % Define segment start and stop indices
            app.SegmentStartIndex = round(app.Field_SegmentStart.Value * app.fs) + 1;
            app.SegmentStopIndex = round((app.Field_SegmentStart.Value + app.Field_SegmentLength.Value) * app.fs);

            % Extract the audio segment
            startIndex = app.SegmentStartIndex;
            stopIndex = app.SegmentStopIndex;
            app.USV_Signal_Segment = app.USV_Signal(startIndex:stopIndex);

            % Define time vector for the segment (in seconds)
            segmentTime = (startIndex:stopIndex) / app.fs;
            
            %
            updateMetrics(app);
            app.Button_PlotPushed();
            app.LoadDetectedUSVButtonPushed();

        end

        % Button pushed function: Button_Plot
        function Button_PlotPushed(app, event)

            % Define segment start and stop indices
            app.SegmentStartIndex = round(app.Field_SegmentStart.Value * app.fs) + 1;
            app.SegmentStopIndex = round((app.Field_SegmentStart.Value + app.Field_SegmentLength.Value) * app.fs);

            % Extract the audio segment
            startIndex = app.SegmentStartIndex;
            stopIndex = app.SegmentStopIndex;
            app.USV_Signal_Segment = app.USV_Signal(startIndex:stopIndex);

            % Define time vector for the segment (in seconds)
            segmentTime = (startIndex:stopIndex) / app.fs;

            % ==================== Plot the Waveform ====================
            plot(app.UIAxes_USV, segmentTime, app.USV_Signal_Segment);
            axis(app.UIAxes_USV, 'tight');
            grid(app.UIAxes_USV, "on");
            xlabel(app.UIAxes_USV, 'Time (s)');
            ylabel(app.UIAxes_USV, 'Amplitude');

            % Set x-axis limits for the waveform plot
            xlim(app.UIAxes_USV, [segmentTime(1), segmentTime(end)]);

            % ==================== Spectrogram Calculation ====================
            % Define spectrogram parameters
            windowSize = app.Field_Spectrogram_WindowSize.Value;
            overlap = app.Field_Spectrogram_WindowOverlap.Value;

            % Compute the spectrogram
            [~, f, t, p] = spectrogram(app.USV_Signal_Segment, windowSize, overlap, [], app.fs, 'yaxis');

            % Convert frequency to kHz
            f_kHz = f / 1000;

            % Convert power to dB scale
            p_dB = 10 * log10(abs(p) + eps); % Add a small value `eps` to avoid log(0)

            % ==================== Plot the Spectrogram ====================
            imagesc(app.UIAxes_Spectrogram, t + app.Field_SegmentStart.Value, f_kHz, p_dB);
            axis(app.UIAxes_Spectrogram, 'xy');
            xlabel(app.UIAxes_Spectrogram, 'Time (s)');
            ylabel(app.UIAxes_Spectrogram, 'Frequency (kHz)');
            colormap(app.UIAxes_Spectrogram, app.Field_Spectrogram_Colormap.Value);

            % Set y-axis limits (frequency range)
            ylim(app.UIAxes_Spectrogram, [app.Field_Spectrogram_FrequencyMin.Value, app.Field_Spectrogram_FrequencyMax.Value]);

            % Set x-axis limits for the spectrogram to match the waveform
            xlim(app.UIAxes_Spectrogram, [segmentTime(1), segmentTime(end)]);

            % ==================== Overlay Labels ====================
            startTime = app.Field_SegmentStart.Value;
            endTime = startTime + app.Field_SegmentLength.Value;

            if app.CheckBox_ShowLabels.Value
                for i = 1:length(app.USV_Labels)
                    if app.USV_Labels(i).StartTime >= startTime && app.USV_Labels(i).EndTime <= endTime
                        labelStart = app.USV_Labels(i).StartTime;
                        labelEnd = app.USV_Labels(i).EndTime;

                        % Plot vertical lines at label start and end times
                        line(app.UIAxes_Spectrogram, [labelStart, labelStart], [app.Field_Spectrogram_FrequencyMin.Value, app.Field_Spectrogram_FrequencyMax.Value], ...
                            'Color', 'w', 'LineWidth', 1.5, 'LineStyle', '--');
                        line(app.UIAxes_Spectrogram, [labelEnd, labelEnd], [app.Field_Spectrogram_FrequencyMin.Value, app.Field_Spectrogram_FrequencyMax.Value], ...
                            'Color', 'w', 'LineWidth', 1.5, 'LineStyle', '-.');

                        % Display label text
                        if ~strcmp(app.USV_Labels(i).Label, "None")
                            text(app.UIAxes_Spectrogram, (labelStart + labelEnd) / 2, app.Field_Spectrogram_FrequencyMax.Value - 5, app.USV_ReferenceLabels(i).Label, ...
                                'Color', 'w','BackgroundColor', 'black', 'FontSize', 15, 'HorizontalAlignment', 'center');
                        end
                    end
                end
            end

            if app.CheckBox_ShowReferenceLabels.Value
                for i = 1:length(app.USV_ReferenceLabels)
                    if app.USV_ReferenceLabels(i).StartTime >= startTime && app.USV_ReferenceLabels(i).EndTime <= endTime
                        labelStart = app.USV_ReferenceLabels(i).StartTime;
                        labelEnd = app.USV_ReferenceLabels(i).EndTime;

                        % Plot vertical lines at label start and end times
                        line(app.UIAxes_Spectrogram, [labelStart, labelStart], [app.Field_Spectrogram_FrequencyMin.Value, app.Field_Spectrogram_FrequencyMax.Value], ...
                            'Color', 'c', 'LineWidth', 1.5, 'LineStyle', '--');
                        line(app.UIAxes_Spectrogram, [labelEnd, labelEnd], [app.Field_Spectrogram_FrequencyMin.Value, app.Field_Spectrogram_FrequencyMax.Value], ...
                            'Color', 'c', 'LineWidth', 1.5, 'LineStyle', '-.');

                        % Display label text
                        if ~strcmp(app.USV_ReferenceLabels(i).Label, "None")
                            text(app.UIAxes_Spectrogram, (labelStart + labelEnd) / 2, app.Field_Spectrogram_FrequencyMax.Value - 5, app.USV_ReferenceLabels(i).Label, ...
                                'Color', 'c','BackgroundColor', 'black', 'FontSize', 15, 'HorizontalAlignment', 'center');
                        end
                    end
                end
            end


            updateMetrics(app);
        end

        % Button pushed function: BreakpointButton
        function BreakpointButtonPushed(app, event)
            x = 0;
        end

        % Value changed function: Field_SelectLabelFile
        function Field_SelectLabelFileValueChanged(app, event)

        end

        % Button pushed function: Button_SelectLabelFile
        function Button_SelectLabelFilePushed(app, event)

            [USV_Label_filename, USV_Label_location] = uigetfile('*.txt');
            app.Field_SelectLabelFile.Value = [USV_Label_location,USV_Label_filename];

        end

        % Value changed function: Field_SelectUSVFile
        function Field_SelectUSVFileValueChanged(app, event)

        end

        % Button pushed function: Button_SelectReferenceLabelFile
        function Button_SelectReferenceLabelFilePushed(app, event)
            [USV_ReferenceLabel_filename, USV_ReferenceLabel_location] = uigetfile('*.txt');
            app.Field_SelectReferenceLabelFile.Value = [USV_ReferenceLabel_location,USV_ReferenceLabel_filename];
        end

        % Button pushed function: Button_SegmentPlus
        function Button_SegmentPlusPushed(app, event)


            app.Field_SegmentStart.Value = app.Field_SegmentStart.Value+app.Field_SegmentLength.Value;
            app.Button_PlotPushed();


        end

        % Button pushed function: Button_SegmentMinus
        function Button_SegmentMinusPushed(app, event)
            app.Field_SegmentStart.Value = app.Field_SegmentStart.Value-app.Field_SegmentLength.Value;
            app.Button_PlotPushed();
        end

        % Button pushed function: Button_Play
        function Button_PlayPushed(app, event)
            % Extract the selected segment
            segment = app.USV_Signal_Segment;

            % Vocoder parameters
            spectralCutFreqHigh = 120000; % Upper frequency limit for spectral cutoff (Hz)
            spectralCutFreqLow = 40000;   % Lower frequency limit for spectral cutoff (Hz)
            audibleLow = 400;
            audibleHigh = 4000;
            ST = -50; % Number of semitones for pitch shifting
            % Apply vocoder effect
            [vocodedSegment, ~] = vocoder(segment, app.fs, spectralCutFreqHigh, spectralCutFreqLow, ST);

            % Resample to a standard sample rate
            targetFs = 44100; % Standard sample rate for playback
            if app.fs ~= targetFs
                vocodedSegment = resample(vocodedSegment, targetFs, app.fs);
                adjustedFs = targetFs;
            else
                adjustedFs = app.fs;
            end

            % Playback duration
            playbackDuration = length(vocodedSegment) / adjustedFs;

            % Play audio using soundsc
            soundsc(vocodedSegment, adjustedFs);

            % Create playhead lines
            hold(app.UIAxes_USV, 'on');
            if isvalid(app.UIAxes_USV)
                playheadLineUSV = plot(app.UIAxes_USV, [app.Field_SegmentStart.Value, app.Field_SegmentStart.Value], app.UIAxes_USV.YLim, 'r', 'LineWidth', 2);
            else
                disp('USV Axes is invalid or deleted.');
                return;
            end
            hold(app.UIAxes_USV, 'off');

            hold(app.UIAxes_Spectrogram, 'on');
            if isvalid(app.UIAxes_Spectrogram)
                playheadLineSpectrogram = plot(app.UIAxes_Spectrogram, [app.Field_SegmentStart.Value, app.Field_SegmentStart.Value], app.UIAxes_Spectrogram.YLim, 'r', 'LineWidth', 2);
            else
                disp('Spectrogram Axes is invalid or deleted.');
                delete(playheadLineUSV); % Cleanup if other axes are invalid
                return;
            end
            hold(app.UIAxes_Spectrogram, 'off');

            % Timer for playhead updates
            try
                tic; % Start timer
                while toc < playbackDuration
                    elapsedTime = toc;
                    xPos = app.Field_SegmentStart.Value + elapsedTime;

                    % Safely update playhead positions
                    if isvalid(playheadLineUSV)
                        playheadLineUSV.XData = [xPos, xPos];
                    end
                    if isvalid(playheadLineSpectrogram)
                        playheadLineSpectrogram.XData = [xPos, xPos];
                    end
                    pause(0.01); % Update every 10 ms
                end
            catch ME
                disp(['Error during playhead update: ', ME.message]);
            end

            % Cleanup after playback
            if isvalid(playheadLineUSV)
                delete(playheadLineUSV);
            end
            if isvalid(playheadLineSpectrogram)
                delete(playheadLineSpectrogram);
            end
        end

        % Button pushed function: LoadDetectedUSVButton
        function LoadDetectedUSVButtonPushed(app, event)
            USVLabelNumber = int32(app.Field_USV_Label_AR.Value);

label = app.USV_Labels_AR(USVLabelNumber);

app.LabelTypeEditField.Value = label.Label;
% Extract the audio segment based on label indices
startIndex = label.StartIndex;
stopIndex = label.StopIndex;
USV_Signal_Segment_AR = app.USV_Signal(startIndex:stopIndex);  % Independent variable

% Define an independent time vector for this spectrogram
segmentTime_AR = (startIndex:stopIndex) / app.fs;

% ==================== Spectrogram Calculation ====================
% Define independent spectrogram parameters
windowSize_AR = app.Field_Spectrogram_WindowSize.Value/4;
overlap_AR = app.Field_Spectrogram_WindowOverlap.Value/4;

% Compute the spectrogram for this independent segment
[~, f_AR, t_AR, p_AR] = spectrogram(USV_Signal_Segment_AR, windowSize_AR, overlap_AR, [], app.fs, 'yaxis');

% Convert frequency to kHz
f_kHz_AR = f_AR / 1000;

% Convert power to dB scale
p_dB_AR = 10 * log10(abs(p_AR) + eps); % Avoid log(0)

% ==================== Plot the Spectrogram ====================
cla(app.UIAxes_Spectrogram_AR, 'reset');  % Clear the axes before plotting
imagesc(app.UIAxes_Spectrogram_AR, t_AR + segmentTime_AR(1), f_kHz_AR, p_dB_AR);
axis(app.UIAxes_Spectrogram_AR, 'xy');
xlabel(app.UIAxes_Spectrogram_AR, 'Time (s)');
ylabel(app.UIAxes_Spectrogram_AR, 'Frequency (kHz)');
colormap(app.UIAxes_Spectrogram_AR, app.Field_Spectrogram_Colormap.Value);

% Set independent y-axis limits
ylim(app.UIAxes_Spectrogram_AR, [app.Field_Spectrogram_FrequencyMin.Value, app.Field_Spectrogram_FrequencyMax.Value]);

% Set x-axis limits for the spectrogram to match the independent time vector
xlim(app.UIAxes_Spectrogram_AR, [segmentTime_AR(1), segmentTime_AR(end)]);


        end

        % Button pushed function: RunDetectionButton
        function RunDetectionButtonPushed(app, event)
            % %
            %  uialert(app.USV_App_Figure, ...
            % ['Error during detection: ', "audio"], ...
            % 'Detection Error', 'Icon', 'error');
            %  pause(2)


            switch  app.DetectorDropDown.Value
                case "PSD"
                    PSD(app);
                case "BSCD"
                    d.Message = "Running BSCD Detection Algorithm";
                otherwise
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            

            outputLabelFile = strcat("AR_",datestr(now, 'yyyy-mm-dd_HH-MM-SS'), "_labels.txt");
fileID = fopen(outputLabelFile, 'w');
%fprintf(fileID, "Start Time (s)\tEnd Time (s)\n"); % Correctly terminated string
for i = 1:length(app.USV_Labels_AR)
    fprintf(fileID, '%.6f\t%.6f\t%s\n', ...
        app.USV_Labels_AR(i).StartTime, app.USV_Labels_AR(i).EndTime, app.USV_Labels_AR(i).Label);
    
    fprintf(fileID, '\\\t%.6f\t%.6f\n', ...
        app.USV_Labels_AR(i).StartFrequency, app.USV_Labels_AR(i).EndFrequency);
end
fclose(fileID);


uialert(app.USV_App_Figure, ...
    sprintf("Successfully exported to %s", pwd), ...
    "AR Export Complete", "Icon", "success");


        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            app.Field_USV_Label_AR.Value = app.Field_USV_Label_AR.Value+1;
            app.LoadDetectedUSVButtonPushed();
        end

        % Button pushed function: RejectButton
        function RejectButtonPushed(app, event)
            USVLabelNumber = int32(app.Field_USV_Label_AR.Value);
            app.USV_Labels_AR(USVLabelNumber) = [];
            app.Field_USV_LabelCount_AR.Value = size(app.USV_Labels_AR,2);
            app.LoadDetectedUSVButtonPushed();

        end

        % Button pushed function: Button_USVAR_Prev
        function Button_USVAR_PrevPushed(app, event)
            if app.Field_USV_Label_AR.Value > 1
                app.Field_USV_Label_AR.Value = app.Field_USV_Label_AR.Value -1;
                app.LoadDetectedUSVButtonPushed();
            end
        end

        % Button pushed function: Button_USVAR_Next
        function Button_USVAR_NextPushed(app, event)
            if app.Field_USV_Label_AR.Value < app.Field_USV_LabelCount_AR.Value
                app.Field_USV_Label_AR.Value = app.Field_USV_Label_AR.Value +1;
                app.LoadDetectedUSVButtonPushed();
            end
        end

        % Value changed function: LabelTypeEditField
        function LabelTypeEditFieldValueChanged(app, event)
            value = app.LabelTypeEditField.Value;
            USVLabelNumber = int32(app.Field_USV_Label_AR.Value);
            app.USV_Labels_AR(USVLabelNumber).Label = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.USV_App_Figure = uifigure('Visible', 'off');
            app.USV_App_Figure.Name = 'USV App | Antonín Gazda';
            app.Tabs = uitabgroup(app.USV_App_Figure);
            app.Tab_Files = uitab(app.Tabs);
            app.Tab_Files.Title = 'Files';
            app.Grid_Files = uigridlayout(app.Tab_Files);
            app.Button_LoadFiles = uibutton(app.Grid_Files, 'push');
            app.Button_LoadFiles.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadFilesPushed, true);
            app.Button_LoadFiles.Text = 'Load files';
            app.Field_SelectLabelFile = uieditfield(app.Grid_Files, 'text');
            app.Field_SelectLabelFile.ValueChangedFcn = createCallbackFcn(app, @Field_SelectLabelFileValueChanged, true);
            app.Field_SelectLabelFile.Placeholder = 'Path to Label File';
            app.Field_SelectLabelFile.Value = '/Users/gazda/Documents/CTU/Masters/Masters thesis/data/labels/LPS-SI2homo-mH02-I04-USV-IVojt.txt';
            app.Button_SelectLabelFile = uibutton(app.Grid_Files, 'push');
            app.Button_SelectLabelFile.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectLabelFilePushed, true);
            app.Button_SelectLabelFile.Text = 'Select Label File (.txt)';
            app.Field_SelectUSVFile = uieditfield(app.Grid_Files, 'text');
            app.Field_SelectUSVFile.ValueChangedFcn = createCallbackFcn(app, @Field_SelectUSVFileValueChanged, true);
            app.Field_SelectUSVFile.Placeholder = 'Path to USV File';
            app.Field_SelectUSVFile.Value = '/Users/gazda/Documents/CTU/Masters/Masters thesis/data/usv_audio/denoise/LPS-SI2homo-mH02-I04-USV_denoise.wav';
            app.Button_SelectUSVFile = uibutton(app.Grid_Files, 'push');
            app.Button_SelectUSVFile.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectUSVFilePushed, true);
            app.Button_SelectUSVFile.Text = 'Select USV file (.wav)';
            app.Field_SelectReferenceLabelFile = uieditfield(app.Grid_Files, 'text');
            app.Field_SelectReferenceLabelFile.Placeholder = 'Path to Label File';
            app.Field_SelectReferenceLabelFile.Value = '/Users/gazda/Documents/CTU/Masters/Masters thesis/data/labels/LPS-SI2homo-mH02-I04-USV-IVojt.txt';
            app.Button_SelectReferenceLabelFile = uibutton(app.Grid_Files, 'push');
            app.Button_SelectReferenceLabelFile.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectReferenceLabelFilePushed, true);
            app.Button_SelectReferenceLabelFile.Text = 'Select Reference Label File (.txt)';
            app.Tab_Visualisation = uitab(app.Tabs);
            app.Tab_Visualisation.Title = 'Visualisation';
            app.Grid_Visualisation = uigridlayout(app.Tab_Visualisation);
            app.UIAxes_Spectrogram = uiaxes(app.Grid_Visualisation);
            title(app.UIAxes_Spectrogram, 'USV - Spectrogram')
            xlabel(app.UIAxes_Spectrogram, 'Time (s)')
            ylabel(app.UIAxes_Spectrogram, 'Frequency (kHz)')
            zlabel(app.UIAxes_Spectrogram, 'Z')
            app.UIAxes_USV = uiaxes(app.Grid_Visualisation);
            title(app.UIAxes_USV, 'USV - Time')
            xlabel(app.UIAxes_USV, 'Time (s)')
            ylabel(app.UIAxes_USV, 'Amplitude (-)')
            zlabel(app.UIAxes_USV, 'Z')
            app.SegmentStartsEditFieldLabel = uilabel(app.Grid_Visualisation);
            app.SegmentStartsEditFieldLabel.Text = 'Segment Start (s)';
            app.SegmentLengthsEditFieldLabel = uilabel(app.Grid_Visualisation);
            app.SegmentLengthsEditFieldLabel.Text = 'Segment Length (s)';
            app.Field_SegmentLength = uieditfield(app.Grid_Visualisation, 'numeric');
            app.Field_SegmentLength.Value = 1;
            app.Field_SegmentStart = uieditfield(app.Grid_Visualisation, 'numeric');
            app.Field_SegmentStart.Value = 61.7;
            app.Button_SegmentPlus = uibutton(app.Grid_Visualisation, 'push');
            app.Button_SegmentPlus.ButtonPushedFcn = createCallbackFcn(app, @Button_SegmentPlusPushed, true);
            app.Button_SegmentPlus.Text = '>';
            app.Button_SegmentMinus = uibutton(app.Grid_Visualisation, 'push');
            app.Button_SegmentMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_SegmentMinusPushed, true);
            app.Button_SegmentMinus.Text = '<';
            app.Button_Plot = uibutton(app.Grid_Visualisation, 'push');
            app.Button_Plot.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotPushed, true);
            app.Button_Plot.Text = 'Plot';
            app.Button_Play = uibutton(app.Grid_Visualisation, 'push');
            app.Button_Play.ButtonPushedFcn = createCallbackFcn(app, @Button_PlayPushed, true);
            app.Button_Play.Text = 'Sonify';
            app.Tab_Statistics = uitab(app.Tabs);
            app.Tab_Statistics.Title = 'Metrics';
            app.GridLayout = uigridlayout(app.Tab_Statistics);
            app.DetectedLabelsEditFieldLabel = uilabel(app.GridLayout);
            app.DetectedLabelsEditFieldLabel.Text = 'Detected  Labels (-)';
            app.DetectedLabelsEditFieldLabel_2 = uilabel(app.GridLayout);
            app.DetectedLabelsEditFieldLabel_2.Text = 'Reference  Labels (-)';
            app.DetectedLabelsEditFieldLabel_3 = uilabel(app.GridLayout);
            app.DetectedLabelsEditFieldLabel_3.Text = ' True Positives (-)';
            app.DetectedLabelsEditFieldLabel_4 = uilabel(app.GridLayout);
            app.DetectedLabelsEditFieldLabel_4.Text = ' False Positives (-)';
            app.DetectedLabelsEditFieldLabel_5 = uilabel(app.GridLayout);
            app.DetectedLabelsEditFieldLabel_5.Text = ' False Negatives (-)';
            app.DetectedLabelsEditFieldLabel_6 = uilabel(app.GridLayout);
            app.DetectedLabelsEditFieldLabel_6.Text = ' Precision (%)';
            app.DetectedLabelsEditFieldLabel_7 = uilabel(app.GridLayout);
            app.DetectedLabelsEditFieldLabel_7.Text = 'Recall (%)';
            app.DetectedLabelsEditFieldLabel_8 = uilabel(app.GridLayout);
            app.DetectedLabelsEditFieldLabel_8.Text = 'F1 Score (-)';
            app.Field_F1Score = uieditfield(app.GridLayout, 'numeric');
            app.Field_F1Score.ValueDisplayFormat = '%.2f';
            app.Field_F1Score.AllowEmpty = 'on';
            app.Field_F1Score.Editable = 'off';
            app.Field_F1Score.Value = [];
            app.Field_Recall = uieditfield(app.GridLayout, 'numeric');
            app.Field_Recall.ValueDisplayFormat = '%.2f';
            app.Field_Recall.AllowEmpty = 'on';
            app.Field_Recall.Editable = 'off';
            app.Field_Recall.Value = [];
            app.Field_Precision = uieditfield(app.GridLayout, 'numeric');
            app.Field_Precision.ValueDisplayFormat = '%.2f';
            app.Field_Precision.AllowEmpty = 'on';
            app.Field_Precision.Editable = 'off';
            app.Field_Precision.Value = [];
            app.Field_FalseNegatives = uieditfield(app.GridLayout, 'numeric');
            app.Field_FalseNegatives.AllowEmpty = 'on';
            app.Field_FalseNegatives.Editable = 'off';
            app.Field_FalseNegatives.Value = [];
            app.Field_FalsePositives = uieditfield(app.GridLayout, 'numeric');
            app.Field_FalsePositives.AllowEmpty = 'on';
            app.Field_FalsePositives.Editable = 'off';
            app.Field_FalsePositives.Value = [];
            app.Field_TruePositives = uieditfield(app.GridLayout, 'numeric');
            app.Field_TruePositives.AllowEmpty = 'on';
            app.Field_TruePositives.Editable = 'off';
            app.Field_TruePositives.Value = [];
            app.Field_ReferenceLabels = uieditfield(app.GridLayout, 'numeric');
            app.Field_ReferenceLabels.AllowEmpty = 'on';
            app.Field_ReferenceLabels.Editable = 'off';
            app.Field_ReferenceLabels.Value = [];
            app.Field_DetectedLabels = uieditfield(app.GridLayout, 'numeric');
            app.Field_DetectedLabels.AllowEmpty = 'on';
            app.Field_DetectedLabels.Editable = 'off';
            app.Field_DetectedLabels.Value = [];
            app.DetectionmetricsLabel = uilabel(app.GridLayout);
            app.DetectionmetricsLabel.Text = 'Detection metrics';
            app.AllLabel = uilabel(app.GridLayout);
            app.AllLabel.Text = 'All';
            app.SegmentLabel = uilabel(app.GridLayout);
            app.SegmentLabel.Text = 'Segment';
            app.Field_DetectedLabels_Segment = uieditfield(app.GridLayout, 'numeric');
            app.Field_DetectedLabels_Segment.AllowEmpty = 'on';
            app.Field_DetectedLabels_Segment.Editable = 'off';
            app.Field_DetectedLabels_Segment.Value = [];
            app.Field_ReferenceLabels_Segment = uieditfield(app.GridLayout, 'numeric');
            app.Field_ReferenceLabels_Segment.AllowEmpty = 'on';
            app.Field_ReferenceLabels_Segment.Editable = 'off';
            app.Field_ReferenceLabels_Segment.Value = [];
            app.Field_TruePositives_Segment = uieditfield(app.GridLayout, 'numeric');
            app.Field_TruePositives_Segment.AllowEmpty = 'on';
            app.Field_TruePositives_Segment.Editable = 'off';
            app.Field_TruePositives_Segment.Value = [];
            app.Field_FalsePositives_Segment = uieditfield(app.GridLayout, 'numeric');
            app.Field_FalsePositives_Segment.AllowEmpty = 'on';
            app.Field_FalsePositives_Segment.Editable = 'off';
            app.Field_FalsePositives_Segment.Value = [];
            app.Field_FalseNegatives_Segment = uieditfield(app.GridLayout, 'numeric');
            app.Field_FalseNegatives_Segment.AllowEmpty = 'on';
            app.Field_FalseNegatives_Segment.Editable = 'off';
            app.Field_FalseNegatives_Segment.Value = [];
            app.Field_Precision_Segment = uieditfield(app.GridLayout, 'numeric');
            app.Field_Precision_Segment.ValueDisplayFormat = '%.2f';
            app.Field_Precision_Segment.AllowEmpty = 'on';
            app.Field_Precision_Segment.Editable = 'off';
            app.Field_Precision_Segment.Value = [];
            app.Field_Recall_Segment = uieditfield(app.GridLayout, 'numeric');
            app.Field_Recall_Segment.ValueDisplayFormat = '%.2f';
            app.Field_Recall_Segment.AllowEmpty = 'on';
            app.Field_Recall_Segment.Editable = 'off';
            app.Field_Recall_Segment.Value = [];
            app.Field_F1Score_Segment = uieditfield(app.GridLayout, 'numeric');
            app.Field_F1Score_Segment.ValueDisplayFormat = '%.2f';
            app.Field_F1Score_Segment.AllowEmpty = 'on';
            app.Field_F1Score_Segment.Editable = 'off';
            app.Field_F1Score_Segment.Value = [];
            app.AcceptRejectTab = uitab(app.Tabs);
            app.AcceptRejectTab.Title = 'Accept/Reject';
            app.GridLayout4 = uigridlayout(app.AcceptRejectTab);
            app.UIAxes_Spectrogram_AR = uiaxes(app.GridLayout4);
            title(app.UIAxes_Spectrogram_AR, 'Detected USV - Spectrogram')
            xlabel(app.UIAxes_Spectrogram_AR, 'Time (s)')
            ylabel(app.UIAxes_Spectrogram_AR, 'Frequency (kHz)')
            zlabel(app.UIAxes_Spectrogram_AR, 'Z')
            app.AcceptButton = uibutton(app.GridLayout4, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.RejectButton = uibutton(app.GridLayout4, 'push');
            app.RejectButton.ButtonPushedFcn = createCallbackFcn(app, @RejectButtonPushed, true);
            app.RejectButton.Text = 'Reject';
            app.LoadDetectedUSVButton = uibutton(app.GridLayout4, 'push');
            app.LoadDetectedUSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDetectedUSVButtonPushed, true);
            app.LoadDetectedUSVButton.Text = 'Load Detected USV';
            app.ExportButton = uibutton(app.GridLayout4, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.LabelEditFieldLabel = uilabel(app.GridLayout4);
            app.LabelEditFieldLabel.Text = '#';
            app.Field_USV_Label_AR = uieditfield(app.GridLayout4, 'numeric');
            app.Field_USV_Label_AR.Value = 1;
            app.LabelEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.LabelEditFieldLabel_2.Text = '/';
            app.Field_USV_LabelCount_AR = uieditfield(app.GridLayout4, 'numeric');
            app.Field_USV_LabelCount_AR.Editable = 'off';
            app.Field_USV_LabelCount_AR.Value = 1;
            app.Button_USVAR_Next = uibutton(app.GridLayout4, 'push');
            app.Button_USVAR_Next.ButtonPushedFcn = createCallbackFcn(app, @Button_USVAR_NextPushed, true);
            app.Button_USVAR_Next.Text = '>';
            app.Button_USVAR_Prev = uibutton(app.GridLayout4, 'push');
            app.Button_USVAR_Prev.ButtonPushedFcn = createCallbackFcn(app, @Button_USVAR_PrevPushed, true);
            app.Button_USVAR_Prev.Text = '<';
            app.LabelTypeEditFieldLabel = uilabel(app.GridLayout4);
            app.LabelTypeEditFieldLabel.Text = 'Label Type';
            app.LabelTypeEditField = uieditfield(app.GridLayout4, 'text');
            app.LabelTypeEditField.ValueChangedFcn = createCallbackFcn(app, @LabelTypeEditFieldValueChanged, true);
            app.DetectionTab = uitab(app.Tabs);
            app.DetectionTab.Title = 'Detection';
            app.GridLayout3 = uigridlayout(app.DetectionTab);
            app.DetectorDropDownLabel = uilabel(app.GridLayout3);
            app.DetectorDropDownLabel.Text = 'Detector';
            app.DetectorDropDown = uidropdown(app.GridLayout3);
            app.DetectorDropDown.Items = {'PSD', 'BSCD'};
            app.DetectorDropDown.Value = 'PSD';
            app.RunDetectionButton = uibutton(app.GridLayout3, 'push');
            app.RunDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunDetectionButtonPushed, true);
            app.RunDetectionButton.Text = 'Run';
            app.ExportButton_2 = uibutton(app.GridLayout3, 'push');
            app.ExportButton_2.Visible = 'off';
            app.ExportButton_2.Text = 'Export';
            app.Tab_Settings = uitab(app.Tabs);
            app.Tab_Settings.Title = 'Settings';
            app.Subtabs_Settings = uitabgroup(app.Tab_Settings);
            app.Subtab_Visualisation = uitab(app.Subtabs_Settings);
            app.Subtab_Visualisation.Title = 'Visualisation';
            app.Grid_VisualisationSettings = uigridlayout(app.Subtab_Visualisation);
            app.WindowSizeLabel = uilabel(app.Grid_VisualisationSettings);
            app.WindowSizeLabel.Text = 'Window Size  (-)';
            app.Field_Spectrogram_Colormap = uidropdown(app.Grid_VisualisationSettings);
            app.Field_Spectrogram_Colormap.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'winter', 'gray', 'jet'};
            app.Field_Spectrogram_Colormap.Value = 'hot';
            app.WindowOverlapLabel = uilabel(app.Grid_VisualisationSettings);
            app.WindowOverlapLabel.Text = 'Window Overlap  (-)';
            app.WindowSizeLabel_2 = uilabel(app.Grid_VisualisationSettings);
            app.WindowSizeLabel_2.Text = 'Frequency Min  (kHz)';
            app.Field_Spectrogram_FrequencyMax = uieditfield(app.Grid_VisualisationSettings, 'numeric');
            app.Field_Spectrogram_FrequencyMax.Value = 120;
            app.Field_Spectrogram_FrequencyMin = uieditfield(app.Grid_VisualisationSettings, 'numeric');
            app.Field_Spectrogram_FrequencyMin.Value = 40;
            app.Field_Spectrogram_WindowOverlap = uieditfield(app.Grid_VisualisationSettings, 'numeric');
            app.Field_Spectrogram_WindowOverlap.Value = 512;
            app.Field_Spectrogram_WindowSize = uieditfield(app.Grid_VisualisationSettings, 'numeric');
            app.Field_Spectrogram_WindowSize.Value = 1024;
            app.Label_SpectrogramSettings = uilabel(app.Grid_VisualisationSettings);
            app.Label_SpectrogramSettings.Text = 'Spectrogram Settings';
            app.WindowSizeLabel_3 = uilabel(app.Grid_VisualisationSettings);
            app.WindowSizeLabel_3.Text = 'Frequency Max  (kHz)';
            app.ColormapDropDownLabel = uilabel(app.Grid_VisualisationSettings);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.CheckBox_ShowLabels = uicheckbox(app.Grid_VisualisationSettings);
            app.CheckBox_ShowLabels.Text = 'Show Labels';
            app.CheckBox_ShowLabels.Value = true;
            app.CheckBox_ShowReferenceLabels = uicheckbox(app.Grid_VisualisationSettings);
            app.CheckBox_ShowReferenceLabels.Text = 'Show Reference Labels';
            app.SonificationTab = uitab(app.Subtabs_Settings);
            app.SonificationTab.Title = 'Sonification';
            app.GridLayout2 = uigridlayout(app.SonificationTab);
            app.DetectionTab_2 = uitab(app.Subtabs_Settings);
            app.DetectionTab_2.Title = 'Detection';
            app.BreakpointButton = uibutton(app.Tab_Settings, 'push');
            app.BreakpointButton.ButtonPushedFcn = createCallbackFcn(app, @BreakpointButtonPushed, true);
            app.BreakpointButton.Text = 'Breakpoint';
            app.Tab_Info = uitab(app.Tabs);
            app.Tab_Info.Title = 'Info';
            app.Grid_Info = uigridlayout(app.Tab_Info);
            app.Label_AppInfo = uilabel(app.Grid_Info);
            app.Label_AppInfo.Text = {'Master''s Thesis: Antonín Gazda'; ''; '2024/25'; ''; 'Supervisor: prof. Ing. Roman Čmejla, CSc.'; ''; 'CTU FEE'};
            % Show the figure after all components are created
            app.USV_App_Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = USV_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.USV_App_Figure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.USV_App_Figure)
        end
    end
end